classdef Vessel3D_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        vessel3D                    matlab.ui.Figure
        VesselSegAxes               matlab.ui.control.UIAxes
        CalculateVesselParametersButton  matlab.ui.control.Button
        LowerBoundSpinnerLabel      matlab.ui.control.Label
        LowerBoundSpinner           matlab.ui.control.Spinner
        WindowSpinnerLabel          matlab.ui.control.Label
        WindowSpinner               matlab.ui.control.Spinner
        IsolateVesselSegmentSwitchLabel  matlab.ui.control.Label
        IsolateVesselSegmentSwitch  matlab.ui.control.Switch
        ReorientButton              matlab.ui.control.Button
    end

    % other properties
    properties (Access = private)
        % handles; % structure for storing variables
        centerline_main_app;
        parameter_plot_app;
        handles;
        hpatch;
        hpatch2;
        plane;
        tb;
        restoreviewbtn;
    end
    
    % functions
    methods (Access = private)
        
        function plot_vessel(app)
            timeMIPvessel = app.handles.timeMIPvessel;
            res = app.handles.res;
            branchActual = app.handles.branchActual;
            segment = app.handles.segment;
            fov = app.handles.fov;
            % distance = handles.distance;
            m_ystart = 1;
            m_ystop = app.handles.res;
            m_xstart = 1;
            m_xstop = app.handles.res;
            m_zstart = 1;
            m_zstop = app.handles.res;

            disp('Visualizing segmented vessel in 3D...');
            cla(app.VesselSegAxes);
            
            app.hpatch = patch(app.VesselSegAxes, isosurface(timeMIPvessel, .25));
            app.hpatch.FaceColor = 'r';
            app.hpatch.EdgeColor = 'none';
            app.hpatch.FaceAlpha = 0.4;
            
            app.hpatch2 = patch(app.VesselSegAxes, isosurface(segment,.5));
            app.hpatch2.FaceColor = 'k';
            app.hpatch2.EdgeColor = 'none';
            app.hpatch2.FaceAlpha = 0.1;

            scale = fov/res;
            distance = zeros(1,length(branchActual));
            distance(1) = scale;
            for i = 2:length(branchActual) - 1
                distance(i) = sqrt((branchActual(i,1)-branchActual(i+1,1))^2+ ...
                    (branchActual(i,2)-branchActual(i+1,2))^2+(branchActual(i,3)-branchActual(i+1,3))^2)*scale + distance(i-1);
            end
            distance(end) = distance(end-1)+scale;
            
            app.VesselSegAxes.ZDir = 'Reverse';
            axis(app.VesselSegAxes, 'vis3d');
            app.VesselSegAxes.Visible = 'Off';
            view(app.VesselSegAxes, [-1 0 0]); % reorient
            app.VesselSegAxes.DataAspectRatio = [1 1 1];
            num = 0;
            hold(app.VesselSegAxes, 'On');
            for i = 1:numel(branchActual(:,1))
                num = num + 1;
                if mod(num-2,5) == 0
                    stringval = {num2str(num-2)};
                    x = branchActual(i,2);
                    y = branchActual(i,1);
                    z = branchActual(i,3);
                    t = text(app.VesselSegAxes, x, y, z, stringval);
                    t.Color = 'b';
                end
            end
            app.VesselSegAxes.XLim = [m_ystart m_ystop];
            app.VesselSegAxes.YLim = [m_xstart m_xstop];
            app.VesselSegAxes.ZLim = [m_zstart m_zstop];
            app.tb = axtoolbar(app.VesselSegAxes, {'rotate', 'pan', 'zoomin', 'zoomout', 'export'});
            app.tb.Visible = 'On';
            p = makeITPlane(app.handles.branchList);
            app.handles.p = p;
            add_plane(app);
        end

        function add_plane(app)
            % delete old planes
            delete(findobj(app.VesselSegAxes, 'Type', 'Patch', '-regexp', 'Tag', 'plane.*?'));
            p = app.handles.p;
            window = app.WindowSpinner.Value;
            voxel_number = app.LowerBoundSpinner.Value;
            offset = 2; % need to offset this; don't know why but was in older code
            for window_slice = 1:window
                pos = app.handles.branchActual(voxel_number + offset + window_slice - 1,:);
                pos = floor(pos);
                x = pos(1); 
                y = pos(2); 
                z = pos(3);
                
                find1 = find(app.handles.branchList(:,1) == x);
                find2 = find(app.handles.branchList(find1,2) ==y);
                find3 = find(app.handles.branchList(find1(find2),3) ==z);
                idx = find1(find2(find3));
                
                hold(app.VesselSegAxes, 'on');    
                app.plane = fill3(app.VesselSegAxes, p(idx,:,2)', p(idx,:,1)', p(idx,:,3)', [1 0 0]);
                app.plane.EdgeColor = [1 0 0];
                app.plane.FaceAlpha = 0.3;
                app.plane.PickableParts = 'none';
                plane_num = ['plane' num2str(window_slice)];
                app.plane.Tag = plane_num;
                hold(app.VesselSegAxes, 'off'); 
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, calling_app, structure)
            app.vessel3D.Name = 'Vessel 3D';
            app.centerline_main_app = calling_app;
            app.handles = structure;
            plot_vessel(app);
            upper_bound = length(app.handles.branchActual);
            app.LowerBoundSpinner.Limits = [1 upper_bound];
            app.WindowSpinner.Limits = [1 upper_bound];
        end

        % Close request function: vessel3D
        function vessel3DCloseRequest(app, event)
            delete(app)
            % delete parameter_plot_gui if it exists
        end

        % Value changed function: WindowSpinner
        function WindowSpinnerValueChanged(app, event)
            add_plane(app);
            app.handles.values.window = app.WindowSpinner.Value;
            % change spinner in parameter_plot_gui --> change plots
        end

        % Value changed function: LowerBoundSpinner
        function LowerBoundSpinnerValueChanged(app, event)
            add_plane(app);
            app.handles.values.lower_bound = app.LowerBoundSpinner.Value;
            % change spinner in parameter_plot_gui --> change plots
        end

        % Button pushed function: CalculateVesselParametersButton
        function CalculateVesselParametersButtonPushed(app, event)
            app.handles.values.window = app.WindowSpinner.Value;
            app.handles.values.lower_bound = app.LowerBoundSpinner.Value;
            app.parameter_plot_app = parameter_plot_gui(app, app.handles);
        end

        % Value changed function: IsolateVesselSegmentSwitch
        function IsolateVesselSegmentSwitchValueChanged(app, event)
            value = app.IsolateVesselSegmentSwitch.Value;
            switch value
                case 'On'
                    app.hpatch2.Visible = 'Off';
                case 'Off'
                    app.hpatch2.Visible = 'On';
            end
        end

        % Button pushed function: ReorientButton
        function ReorientButtonPushed(app, event)
            view(app.VesselSegAxes, [-1 0 0]); % reorient
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.vessel3D = uifigure('Visible', 'off');
            app.vessel3D.Name = 'UI Figure';
            app.vessel3D.CloseRequestFcn = createCallbackFcn(app, @vessel3DCloseRequest, true);
            app.VesselSegAxes = uiaxes(app.vessel3D);
            title(app.VesselSegAxes, '')
            xlabel(app.VesselSegAxes, '')
            ylabel(app.VesselSegAxes, '')
            app.CalculateVesselParametersButton = uibutton(app.vessel3D, 'push');
            app.CalculateVesselParametersButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateVesselParametersButtonPushed, true);
            app.CalculateVesselParametersButton.Text = 'Calculate Vessel Parameters';
            app.LowerBoundSpinnerLabel = uilabel(app.vessel3D);
            app.LowerBoundSpinnerLabel.Text = 'Lower Bound';
            app.LowerBoundSpinner = uispinner(app.vessel3D);
            app.LowerBoundSpinner.Limits = [1 Inf];
            app.LowerBoundSpinner.ValueChangedFcn = createCallbackFcn(app, @LowerBoundSpinnerValueChanged, true);
            app.LowerBoundSpinner.Value = 1;
            app.WindowSpinnerLabel = uilabel(app.vessel3D);
            app.WindowSpinnerLabel.Text = 'Window';
            app.WindowSpinner = uispinner(app.vessel3D);
            app.WindowSpinner.Limits = [1 Inf];
            app.WindowSpinner.ValueChangedFcn = createCallbackFcn(app, @WindowSpinnerValueChanged, true);
            app.WindowSpinner.Value = 5;
            app.IsolateVesselSegmentSwitchLabel = uilabel(app.vessel3D);
            app.IsolateVesselSegmentSwitchLabel.Text = 'Isolate Vessel Segment';
            app.IsolateVesselSegmentSwitch = uiswitch(app.vessel3D, 'slider');
            app.IsolateVesselSegmentSwitch.ValueChangedFcn = createCallbackFcn(app, @IsolateVesselSegmentSwitchValueChanged, true);
            app.ReorientButton = uibutton(app.vessel3D, 'push');
            app.ReorientButton.ButtonPushedFcn = createCallbackFcn(app, @ReorientButtonPushed, true);
            app.ReorientButton.Text = 'Reorient';
            % Show the figure after all components are created
            app.vessel3D.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Vessel3D_gui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.vessel3D)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.vessel3D)
        end
    end
end