classdef background_phase_correction_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        background_phase_correction  matlab.ui.Figure
        mag_axes                     matlab.ui.control.UIAxes
        velocity_axes                matlab.ui.control.UIAxes
        status_axes                  matlab.ui.control.UIAxes
        image_slider                 matlab.ui.control.Slider
        mag_label                    matlab.ui.control.Label
        velocity_label               matlab.ui.control.Label
        update_button                matlab.ui.control.Button
        fit_order_box                matlab.ui.control.EditField
        fit_order_label              matlab.ui.control.Label
        apply_correction_cb          matlab.ui.control.CheckBox
        vmax_slider                  matlab.ui.control.Slider
        vmax_label                   matlab.ui.control.Label
        noise_label                  matlab.ui.control.Label
        cd_label                     matlab.ui.control.Label
        cd_slider                    matlab.ui.control.Slider
        noise_slider                 matlab.ui.control.Slider
        done_button                  matlab.ui.control.Button
        iteration_label              matlab.ui.control.Label
        reset_fit_button             matlab.ui.control.Button
    end

    properties (Access = private)
        handles;
        centerline_main_app;
    end
    
    % Functions
    methods (Access = private)
        function polyfit_3D(app)
            Mask = int8(zeros(size(app.handles.MAG)));
            max_MAG = max(app.handles.MAG(:));
            noise_thresh = app.noise_slider.Value;
            cd_thresh = app.cd_slider.Value;
            
            % Create angiogram
            for slice = 1:size(Mask,3)
            
                % Grab a slice
                mag_slice= single(app.handles.MAG(:,:,slice));
                vx_slice = single(app.handles.VX(:,:,slice));
                vy_slice = single(app.handles.VY(:,:,slice));
                vz_slice = single(app.handles.VZ(:,:,slice));
            
            %     [y,x,z] = meshgrid( app.xrange,app.yrange,app.zrange(slice) );
            %     vx_slice = vx_slice - evaluate_poly(x,y,z,app.poly_vals{1},app.px,app.py,app.pz);
            %     vy_slice = vy_slice - evaluate_poly(x,y,z,app.poly_vals{2},app.px,app.py,app.pz);
            %     vz_slice = vz_slice - evaluate_poly(x,y,z,app.poly_vals{3},app.px,app.py,app.pz);
            
                CD = sqrt(vx_slice.^2 + vy_slice.^2 + + vz_slice.^2);
                Mask(:,:,slice) = (mag_slice > noise_thresh*max_MAG) .* (CD < cd_thresh*app.handles.VENC);
            end

            % Lots of memory problems with vectorization!!! solve Ax = B by (A^hA) x = (A^h *b)
            if fit_order == 0
                px = 0;
                py = 0;
                pz = 0;
            
                app.handles.poly_fitx.vals  = mean(app.handles.VX(:));
                app.handles.poly_fitx.px = 0;
                app.handles.poly_fitx.py = 0;
                app.handles.poly_fitx.pz = 0;
            
                app.handles.poly_fity.vals  = mean(app.handles.VY(:));
                app.handles.poly_fity.px = 0;
                app.handles.poly_fity.py = 0;
                app.handles.poly_fity.pz = 0;
            
                app.handles.poly_fitz.vals  = mean(app.handles.VZ(:));
                app.handles.poly_fitz.px = 0;
                app.handles.poly_fitz.py = 0;
                app.handles.poly_fitz.pz = 0;
            else
                fit_order = str2num(app.fit_order_box.Value);
                [px,py,pz] = meshgrid(0:fit_order, 0:fit_order, 0:fit_order);
                idx2 = find((px+py+pz) <= fit_order);
                px = px(idx2);
                py = py(idx2);
                pz = pz(idx2);
                A = [px(:) py(:) pz(:)];
            
                N = size(A,1);
            
                AhA = zeros(N,N);
                AhBx = zeros(N,1);
                AhBy = zeros(N,1);
                AhBz = zeros(N,1);
            
                xrange = single(linspace(-1,1,size(app.handles.VX,1)));
                yrange = single(linspace(-1,1,size(app.handles.VY,2)));
                zrange = single(linspace(-1,1,size(app.handles.VZ,3)));
            
                % axes(app.status_axes); %%%%%%%%%%%%%%
                I = zeros(2, numel(zrange));
            
                for slice = 1:numel(zrange)
            
                    I(:,slice) = 1;
                    imshow(app.status_axes, I, [0 1]);
                    app.status_axes.DataAspectRatio = [13 1 1];
                    app.status_axes.XTickLabel = '';
                    app.status_axes.YTickLabel = '';
                    drawnow;
            
                    vx_slice = single(app.handles.VX(:,:,slice) );
                    vy_slice = single(app.handles.VY(:,:,slice) );
                    vz_slice = single(app.handles.VZ(:,:,slice) );
            
                    [y,x,z] = meshgrid(yrange,xrange,zrange(slice));
                    idx = find(Mask(:,:,slice) > 0);
                    x = x(idx);
                    y = y(idx);
                    z = z(idx);
                    vx_slice = vx_slice(idx);
                    vy_slice = vy_slice(idx);
                    vz_slice = vz_slice(idx);
            
                    for i = 1:N
                        for j = 1:N
                            AhA(i,j) =  AhA(i,j) + sum((x.^px(i).*y.^py(i).*z.^pz(i)).*((x.^px(j).*y.^py(j).*z.^pz(j))));
                        end
                    end
            
                    for i = 1:N
                        AhBx(i) = AhBx(i) + sum( vx_slice.* ( x.^px(i).*y.^py(i).*z.^pz(i)));
                        AhBy(i) = AhBy(i) + sum( vy_slice.* ( x.^px(i).*y.^py(i).*z.^pz(i)));
                        AhBz(i) = AhBz(i) + sum( vz_slice.* ( x.^px(i).*y.^py(i).*z.^pz(i)));
                    end
                end
            
                % save variables
                app.handles.poly_fitx.vals = linsolve(AhA, AhBx);
                app.handles.poly_fitx.px = px;
                app.handles.poly_fitx.py = py;
                app.handles.poly_fitx.pz = pz;
            
                app.handles.poly_fity.vals = linsolve(AhA, AhBy);
                app.handles.poly_fity.px = px;
                app.handles.poly_fity.py = py;
                app.handles.poly_fity.pz = pz;
            
                app.handles.poly_fitz.vals = linsolve(AhA, AhBz);
                app.handles.poly_fitz.px = px;
                app.handles.poly_fitz.py = py;
                app.handles.poly_fitz.pz = pz;
            end
        end
        
        function update_images(app)
            cd_thresh = app.cd_slider.Value;
            noise_thresh = 0.3*app.noise_slider.Value;
            rczres = size(app.handles.MAG,3);
            slice = 1 + floor(app.image_slider.Value*(rczres-1));
            apply_pc = app.apply_correction_cb.Value;
            
            % Get magnitude
            vx_slice = single(app.handles.VX(:,:,slice));
            vy_slice = single(app.handles.VY(:,:,slice));
            vz_slice = single(app.handles.VZ(:,:,slice));
            mag_slice= single(app.handles.MAG(:,:,slice));
            
            % Range
            if apply_pc == 1
                [y,x,z] = meshgrid(app.handles.yrange, app.handles.xrange, app.handles.zrange(slice));
                vx_slice = vx_slice - evaluate_poly(x,y,z,app.handles.poly_fitx);
                vy_slice = vy_slice - evaluate_poly(x,y,z,app.handles.poly_fity);
                vz_slice = vz_slice - evaluate_poly(x,y,z,app.handles.poly_fitz);
            end
            
            vmag = sqrt(vx_slice.^2 + vy_slice.^2 + + vz_slice.^2);          
            idx = find((mag_slice > noise_thresh*app.handles.max_mag) & (vmag < cd_thresh*app.handles.VENC));
            mag_slice = 200*mag_slice/max(mag_slice(:));
            mag_slice(idx) = 257;
            
            map = [gray(200); jet(10)];
            imagesc(app.mag_axes, mag_slice, [0,200+10]);
            app.mag_axes.XTickLabel = '';
            app.mag_axes.YTickLabel = '';
            app.mag_axes.Colormap = map;
            app.mag_axes.DataAspectRatio = [1 1 1];
            
            
            vmax = floor(500*app.vmax_slider.Value');
            app.vmax_label.String = num2str(vmax);
            
            vel_mag = sqrt(vx_slice.^2 + vy_slice.^2 + vz_slice.^2);
            vel_mag = 200*vel_mag/vmax;
            idx = vel_mag >= 199;
            vel_mag(idx) = 199;
            
            imagesc(app.velocity_axes, vel_mag, [0 210]); %.*MAG(:,:,slice));
            app.velocity_axes.XTickLabel = '';
            app.velocity_axes.YTickLabel = '';
            app.velocity_axes.Colormap = map;
            app.velocity_axes.DataAspectRatio = [1 1 1];
            drawnow;
        end
        
        function val = evaluate_poly(app,x,y,z,fit)
            val = 0;
            for i = 1:numel(fit.px)
                val = val + fit.vals(i)*( x.^fit.px(i).*y.^fit.py(i).*z.^fit.pz(i));
            end
        end
                
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function background_phase_correction_OpeningFcn(app, calling_app, structure)
            app.background_phase_correction.Name= 'Background Phase Correction';
            app.centerline_main_app = calling_app;
            app.handles = structure;
            app.handles.VENC = 1000;
            
            app.status_axes.XTick = [];
            app.status_axes.YTick = [];
            app.status_axes.XTickLabel = '';
            app.status_axes.YTickLabel = '';
            app.status_axes.Box = 'Off';
                        
            % Load Velocity Data
            app.handles.VX = app.handles.vMean(:,:,:,1);
            app.handles.VY = app.handles.vMean(:,:,:,2);
            app.handles.VZ = app.handles.vMean(:,:,:,3);             
            app.handles.max_mag = max(app.handles.MAG(:));
             
            app.handles.poly_fitx.vals = 0;
            app.handles.poly_fitx.px = 0;
            app.handles.poly_fitx.py = 0;
            app.handles.poly_fitx.pz = 0;
            
            app.handles.poly_fity.vals = 0;
            app.handles.poly_fity.px = 0;
            app.handles.poly_fity.py = 0;
            app.handles.poly_fity.pz = 0;
            
            app.handles.poly_fitz.vals = 0;
            app.handles.poly_fitz.px = 0;
            app.handles.poly_fitz.py = 0;
            app.handles.poly_fitz.pz = 0;
           
            % Range
            app.handles.xrange = single(linspace(-1,1,size(app.handles.VX,1)));
            app.handles.yrange = single(linspace(-1,1,size(app.handles.VY,2)));
            app.handles.zrange = single(linspace(-1,1,size(app.handles.VZ,3)));
             
            update_images(app);
        end

        % Value changed function: cd_slider
        function cd_slider_Callback(app, event)
            update_images(app);
        end

        % Value changed function: noise_slider
        function noise_slider_Callback(app, event)
            update_images(app);
        end

        % Value changed function: vmax_slider
        function vmax_slider_Callback(app, event)
            update_images(app);
        end

        % Value changed function: apply_correction_cb
        function apply_correction_cb_Callback(app, event)
            update_images(app);
        end

        % Value changed function: image_slider
        function image_slider_Callback(app, event)
            update_images(app);
        end

        % Button pushed function: reset_fit_button
        function reset_fit_button_Callback(app, event)
            app.handles.poly_fitx.vals = 0;
            app.handles.poly_fitx.px = 0;
            app.handles.poly_fitx.py = 0;
            app.handles.poly_fitx.pz = 0;
            
            app.handles.poly_fity.vals = 0;
            app.handles.poly_fity.px = 0;
            app.handles.poly_fity.py = 0;
            app.handles.poly_fity.pz = 0;
            
            app.handles.poly_fitz.vals = 0;
            app.handles.poly_fitz.px = 0;
            app.handles.poly_fitz.py = 0;
            app.handles.poly_fitz.pz = 0;
            
            update_images(app);
%             iter = 0;
%             app.iteration_label.Text = num2str(iter);
        end

        % Button pushed function: update_button
        function update_button_Callback(app, event)
            app.handles.fit_order = str2double(app.fit_order_box.Value);
            app.handles.cd_thresh = app.cd_slider.Value;
            app.handles.noise_thresh = 0.3*app.noise_slider.Value;

            polyfit_3D(app);
            update_images(app);

            iter = 0;
            iter = iter + 1;
            app.iteration_label.Text = num2str(iter);
        end

        % Button pushed function: done_button
        function done_button_Callback(app, event)
            close(app.background_phase_correction);
        end

        % Close request function: background_phase_correction
        function background_phase_correction_CloseRequestFcn(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.background_phase_correction = uifigure('Visible', 'off');
            app.background_phase_correction.Name = 'Background Phase Correction Tool';
            app.background_phase_correction.CloseRequestFcn = createCallbackFcn(app, @background_phase_correction_CloseRequestFcn, true);
            app.mag_axes = uiaxes(app.background_phase_correction);
            app.velocity_axes = uiaxes(app.background_phase_correction);
            app.status_axes = uiaxes(app.background_phase_correction);
            app.image_slider = uislider(app.background_phase_correction);
            app.image_slider.Limits = [0 1];
            app.image_slider.MajorTicks = [];
            app.image_slider.ValueChangedFcn = createCallbackFcn(app, @image_slider_Callback, true);
            app.image_slider.MinorTicks = [];
            app.image_slider.Value = 0.5;
            app.mag_label = uilabel(app.background_phase_correction);
            app.mag_label.Text = 'Magnitude Image';
            app.velocity_label = uilabel(app.background_phase_correction);
            app.velocity_label.Text = 'Velocity Magnitude';
            app.update_button = uibutton(app.background_phase_correction, 'push');
            app.update_button.ButtonPushedFcn = createCallbackFcn(app, @update_button_Callback, true);
            app.update_button.Text = 'Update Images';
            app.fit_order_box = uieditfield(app.background_phase_correction, 'text');
            app.fit_order_box.Value = '2';
            app.fit_order_label = uilabel(app.background_phase_correction);
            app.fit_order_label.Text = 'Fit Order';
            app.apply_correction_cb = uicheckbox(app.background_phase_correction);
            app.apply_correction_cb.ValueChangedFcn = createCallbackFcn(app, @apply_correction_cb_Callback, true);
            app.apply_correction_cb.Text = 'Apply Correction';
            app.apply_correction_cb.Value = true;
            app.vmax_slider = uislider(app.background_phase_correction);
            app.vmax_slider.Limits = [0 1];
            app.vmax_slider.MajorTicks = [];
            app.vmax_slider.ValueChangedFcn = createCallbackFcn(app, @vmax_slider_Callback, true);
            app.vmax_slider.MinorTicks = [];
            app.vmax_slider.Value = 0.1;
            app.vmax_label = uilabel(app.background_phase_correction);
            app.vmax_label.Text = 'vmax';
            app.noise_label = uilabel(app.background_phase_correction);
            app.noise_label.Text = 'Noise';
            app.cd_label = uilabel(app.background_phase_correction);
            app.cd_label.Text = 'CD';
            app.cd_slider = uislider(app.background_phase_correction);
            app.cd_slider.Limits = [0 1];
            app.cd_slider.MajorTicks = [];
            app.cd_slider.ValueChangedFcn = createCallbackFcn(app, @cd_slider_Callback, true);
            app.cd_slider.MinorTicks = [];
            app.cd_slider.Value = 0.15;
            app.noise_slider = uislider(app.background_phase_correction);
            app.noise_slider.Limits = [0 1];
            app.noise_slider.MajorTicks = [];
            app.noise_slider.ValueChangedFcn = createCallbackFcn(app, @noise_slider_Callback, true);
            app.noise_slider.MinorTicks = [];
            app.noise_slider.Value = 0.15;
            app.done_button = uibutton(app.background_phase_correction, 'push');
            app.done_button.ButtonPushedFcn = createCallbackFcn(app, @done_button_Callback, true);
            app.done_button.Text = 'Done';
            app.iteration_label = uilabel(app.background_phase_correction);
            app.iteration_label.Text = 'Iterations';
            app.reset_fit_button = uibutton(app.background_phase_correction, 'push');
            app.reset_fit_button.ButtonPushedFcn = createCallbackFcn(app, @reset_fit_button_Callback, true);
            app.reset_fit_button.Text = 'Reset Fit';
            % Show the figure after all components are created
            app.background_phase_correction.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = background_phase_correction_gui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.background_phase_correction)

            % Execute the startup function
            runStartupFcn(app, @(app)background_phase_correction_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.background_phase_correction)
        end
    end
end