classdef centerline_tool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        centerline_tool_main          matlab.ui.Figure
        LoadDataButton                matlab.ui.control.Button
        DataDirectoryLabel            matlab.ui.control.Label
        Vasculature3DAxes             matlab.ui.control.UIAxes
        DrawROIButton                 matlab.ui.control.Button
        ViewParametricMapButton       matlab.ui.control.Button
        FeatureExtractionButton       matlab.ui.control.Button
        SelectVesselandSegmentButton  matlab.ui.control.Button
        ReorientButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        Vessel3D_app; % vessel 3D GUI
        % background_phase_correction_app; % background phase correction GUI
        hpatch; % patch for 3D vasculature
        handles = struct(); % structure for storing variables
        tb;
        %%% testing only!
        %handles = evalin('base', 'vipr_handles');
        %%%
    end
    
    % load pc vipr functions
    methods (Access = private)    
        function get_vipr_directory(app)
            % Get and load input directory
            app.handles.directory = uigetdir;
            tic
            delimiter = ' ';
        
            %Read columns of data as strings:
            % For more information, see the TEXTSCAN documentation.
            formatSpec = '%s%s%[^\n\r]';
        
            if app.handles.directory ~= 0
                fid = fopen([app.handles.directory '\pcvipr_header.txt'], 'r');%read header
                % Read columns of data according to format string.
                app.handles.dataArray = textscan(fid, formatSpec, 'Delimiter', delimiter, 'MultipleDelimsAsOne', true,  'ReturnOnError', false);
                %header
                fclose(fid);
            end
        end
        
        function load_vipr_data(app)
            %convert string to num
            app.handles.dataArray{1,2} = cellfun(@str2num, app.handles.dataArray{1,2}(:), 'un', 0);
            pcviprheader = cell2struct(app.handles.dataArray{1,2}(:), app.handles.dataArray{1,1}(:), 1)
        
            nframes = pcviprheader.frames;              % number of reconstructed frames
            timeres = pcviprheader.timeres;             % temporal resolution
            fov = (pcviprheader.fovx)/10;               % Field of view in cm
            res = pcviprheader.matrixx;                 % number of pixels in row,col,slices
            v = zeros(res,res,res,3,nframes, 'int16');  % init 4D flow matrix, three directions, 20 time frames
        
            for m = 0 : nframes - 1 % looped reading of all velocity images
        
                fida1 = fopen([app.handles.directory '/ph_' num2str(m,'%03i') '_vd_1.dat'], 'r');
                v(:,:,:,1,m+1) = reshape(fread(fida1,res^3,'short')',res,res,res);
                fclose(fida1);
        
                fida2 = fopen([app.handles.directory '/ph_' num2str(m,'%03i') '_vd_2.dat'], 'r');
                v(:,:,:,2,m+1) = reshape(fread(fida2,res^3,'short')',res,res,res);
                fclose(fida2);
        
                fida3 = fopen([app.handles.directory '/ph_' num2str(m,'%03i') '_vd_3.dat'], 'r');
                v(:,:,:,3,m+1) = reshape(fread(fida3,res^3,'short')',res,res,res);
                fclose(fida3);
        
                disp(['   Completed reading frame ', num2str(m+1), ' of ', num2str(nframes)]);
            end
            v = single(v);
            disp('Reading Composite Data...');
            MAG = load_dat(app, fullfile(app.handles.directory,'MAG.dat'),[res res res]);
            vMean = single(zeros(res,res,res,3));
            vMean(:,:,:,1) = load_dat(app, fullfile(app.handles.directory,'comp_vd_1.dat'),[res res res]);
            vMean(:,:,:,2) = load_dat(app, fullfile(app.handles.directory,'comp_vd_2.dat'),[res res res]);
            vMean(:,:,:,3) = load_dat(app, fullfile(app.handles.directory,'comp_vd_3.dat'),[res res res]);
            
            % save variables
            app.handles.nframes = nframes;
            app.handles.res = res;
            app.handles.fov = fov;
            app.handles.timeres = timeres;
            app.handles.v = v;
            app.handles.MAG = MAG;
            app.handles.vMean = vMean;
            app.handles.VENC = pcviprheader.VENC;
        end
            
        function poly_correction(app) 
            % Calculate a Polynomial
            [poly_fitx, poly_fity, poly_fitz] = background_phase_correction_gui(app);
        
            disp('Correcting Data with Polynomial...');
            xrange = single( linspace(-1,1,size(app.handles.MAG,1)));
            yrange = single( linspace(-1,1,size(app.handles.MAG,2)));
            zrange = single( linspace(-1,1,size(app.handles.MAG,3)));
            [y,x,z] = meshgrid( yrange,xrange,zrange);
        
            disp('   Vx');
            back = evaluate_poly(app,x,y,z,poly_fitx);
            back = single(back);
            app.handles.vMean(:,:,:,1) = app.handles.vMean(:,:,:,1) - back;
            for m = 0 : app.handles.nframes - 1
                app.handles.v(:,:,:,1,m+1) = app.handles.v(:,:,:,1,m+1) - back;
            end
        
            disp('   Vy');
            back = evaluate_poly(app,x,y,z,poly_fity);
            back = single(back);
            app.handles.vMean(:,:,:,2) = app.handles.vMean(:,:,:,2) - back;
            for m = 0 : app.handles.nframes - 1
                app.handles.v(:,:,:,2,m+1) = app.handles.v(:,:,:,2,m+1) - back;
            end
        
            disp('   Vz');
            back = evaluate_poly(app,x,y,z,poly_fitz);
            back = single(back);
            app.handles.vMean(:,:,:,3) = app.handles.vMean(:,:,:,3) - back;
            for m = 0 : app.handles.nframes - 1
                app.handles.v(:,:,:,3,m+1) = app.handles.v(:,:,:,3,m+1) - back;
            end
        
            % This section will be used in the future but is in the validation process
            % % Calculate Angiogram after the fact
            disp('Recalculating Angiogram...');
            timeMIP = calc_angio(app, app.handles.MAG, app.handles.vMean, app.handles.VENC);
        
            % save variables
            app.handles.timeMIP = timeMIP;
        end
        
        function v = load_dat(app, name, res)
            [fid,errmsg]= fopen(name,'r');
            if fid < 0
                disp(['Error Opening Data : ',errmsg]);
            end
            v = single(reshape(fread(fid,'short'),res));
            fclose(fid);
        end
        
        function val = evaluate_poly(app,x,y,z,fit)
            val = 0;
            for i = 1:numel(fit.px)
                val = val + fit.vals(i)*( x.^fit.px(i).*y.^fit.py(i).*z.^fit.pz(i));
            end
        end
        
        function angio = calc_angio(app, MAG, vMean, Venc)
            angio = zeros(size(MAG),'single');
            Vmag = sqrt(sum(vMean.^2,4));
            idx = find(Vmag>Venc);
            Vmag(idx) = Venc;
            angio = 32000*MAG.*sin(pi/2*Vmag/Venc);
        end
        
        function view_angio(app)
            disp('View 3D Vasculature');
            
            m_xstart = 1; 
            m_ystart = 1; 
            m_zstart = 1;
            m_xstop = app.handles.res; 
            m_ystop = app.handles.res; 
            m_zstop = app.handles.res;

            timeMIP2 = zeros(size(app.handles.timeMIP));
            timeMIP2(m_xstart:m_xstop, m_ystart:m_ystop, m_zstart:m_zstop) = 1;
            timeMIP_crop = app.handles.timeMIP.*timeMIP2;
            vMean_crop = zeros(size(app.handles.vMean));
            vMean_crop(:,:,:,1) = timeMIP2.*app.handles.vMean(:,:,:,1);
            vMean_crop(:,:,:,2) = timeMIP2.*app.handles.vMean(:,:,:,2);
            vMean_crop(:,:,:,3) = timeMIP2.*app.handles.vMean(:,:,:,3);
            
            normed_MIP = timeMIP_crop(:)./max(timeMIP_crop(:));
            [muhat,sigmahat] = normfit(normed_MIP);
            
            segment = zeros(size(timeMIP_crop));
            segment(normed_MIP>muhat+4.5*sigmahat) = 1;
            
            % The value at the end of the commnad in the minimum area of each segment to keep 
            segment = bwareaopen(segment,round(sum(segment(:)).*0.005),6); 
            % Fill in holes created by slow flow on the inside of vessels
            segment = imfill(segment,'holes'); 
            segment = single(segment);
            
            % plot angiogram
            cla(app.Vasculature3DAxes);
            app.hpatch = patch(app.Vasculature3DAxes, isosurface(segment,0.5));
            app.hpatch.FaceColor = 'red';
            app.hpatch.EdgeColor = 'None';
            % reducepatch(hpatch,0.6);
            app.hpatch.FaceAlpha = 0.4;
            
            % Make it all look good
            % colormap('gray');
            app.Vasculature3DAxes.ZDir = 'reverse';
            app.Vasculature3DAxes.Color = 'black';
            app.Vasculature3DAxes.Colormap = 'gray';
            % alpha(0.9)
            axis(app.Vasculature3DAxes, 'vis3d');
            app.Vasculature3DAxes.Visible = 'off';
            app.Vasculature3DAxes.View = [-.5 0 0];
            app.Vasculature3DAxes.DataAspectRatio = [1 1 1];
            app.Vasculature3DAxes.XLim = [m_ystart m_ystop];
            app.Vasculature3DAxes.YLim = [m_xstart m_xstop];
            app.Vasculature3DAxes.ZLim = [m_zstart m_zstop];
            % camlight headlight;
            % lighting gouraud;
            
            % save variables
            app.handles.segment = segment;
        end
        
    end
    
    % preprocessing functions
    methods (Access = private)
        
        function skeleton_3d(app)
            % Parallel medial axis thinning of a 3D binary volume
            %
            % MATLAB vectorized implementation of the algorithm by
            % Ta-Chih Lee, Rangasami L. Kashyap and Chong-Nam Chu
            % "Building skeleton models via 3-D medial surface/axis thinning algorithms."
            % Computer Vision, Graphics, and Image Processing, 56(6):462–478, 1994.
            %
            % Inspired by the ITK implementation by Hanno Homann
            % http://hdl.handle.net/1926/1292
            % and the Fiji/ImageJ plugin by Ignacio Arganda-Carreras
            % http://fiji.sc/wiki/index.php/Skeletonize3D
            %
            %
            % Philip Kollmannsberger 9/2013
            % philipk@gmx.net
            %
            % Updated: Carson Hoffman 3/22/2016
            % cahoffman@wisc.edu
            
            % function skel = Skeleton3D(img,spare)
            
            disp('computing medial axis............................');
            
            % pad volume with zeros to avoid edge effects
            skel=padarray(img,[1 1 1]);
            
            if(nargin==2)
                spare=padarray(spare,[1 1 1]);
            end
            
            % number of foreground voxels
            l_orig = length(find(skel(:)));
            
            % fill lookup table
            eulerLUT = FillEulerLUT;
            
            % number of iterations
            iter = 1;
            
            global width height depth
            
            width = size(skel,1);
            height = size(skel,2);
            depth = size(skel,3);
            
            ind_dir = [1 -1 width -width width*height -width*height];
            
            unchangedBorders = 0;
            
            while( unchangedBorders < 6 )  % loop until no change for all six border types
                unchangedBorders = 0;
                for currentBorder=1:6 % loop over all 6 directions
                    skel_ind = find(skel(:)==1);
                    switch currentBorder
                        case 4
                         cands = skel_ind+ind_dir(1,2);
                         cands = skel_ind(~logical(skel(cands)));
                        case 3
                         cands = skel_ind+ind_dir(1,1);
                         cands = skel_ind(~logical(skel(cands)));
                        case 1
                         cands = skel_ind+ind_dir(1,4);
                         cands = skel_ind(~logical(skel(cands)));
                        case 2
                         cands = skel_ind+ind_dir(1,3);
                         cands = skel_ind(~logical(skel(cands)));
                        case 6
                         cands = skel_ind+ind_dir(1,6);
                         cands = skel_ind(~logical(skel(cands)));
                        case 5
                         cands = skel_ind+ind_dir(1,5);
                         cands = skel_ind(~logical(skel(cands)));
                    end
                    
                    % if excluded voxels were passed, remove them from candidates
                    if(nargin==2)
                        cands = cands.*~spare;
                    end
                    
                    % make sure all candidates are indeed foreground voxels
                    cands = intersect(cands,skel_ind);
                    
                    noChange = true;
                                
                    if(~isempty(cands))
                        % get subscript indices of candidates
                        [x y z]=ind2sub([width height depth],cands);
                        
                        % get 26-neighbourhood of candidates in volume
                        nhood = logical(pk_get_nh(skel,cands));
                        
                        % remove all endpoints (exactly one nb) from list
                        di1 = find(sum(nhood,2)==2);
                        nhood(di1,:)=[];
                        cands(di1)=[];
                        x(di1)=[];
                        y(di1)=[];
                        z(di1)=[];
                        
                        % remove all non-Euler-invariant points from list
                        di2 = find(~p_EulerInv(nhood, eulerLUT'));
                        nhood(di2,:)=[];
                        cands(di2)=[];
                        x(di2)=[];
                        y(di2)=[];
                        z(di2)=[];
                        
                        % remove all non-simple points from list
                        di3 = find(~p_is_simple(nhood));
                        nhood(di3,:)=[];
                        cands(di3)=[];
                        x(di3)=[];
                        y(di3)=[];
                        z(di3)=[];
                        
                        
                        % if any candidates left: divide into 8 independent subvolumes
                        if(~isempty(x))
                            x1 = find(mod(x,2));
                            x2 = find(~mod(x,2));
                            y1 = find(mod(y,2));
                            y2 = find(~mod(y,2));
                            z1 = find(mod(z,2));
                            z2 = find(~mod(z,2));
                            ilst(1).l = intersect(x1,intersect(y1,z1));
                            ilst(2).l = intersect(x2,intersect(y1,z1));
                            ilst(3).l = intersect(x1,intersect(y2,z1));
                            ilst(4).l = intersect(x2,intersect(y2,z1));
                            ilst(5).l = intersect(x1,intersect(y1,z2));
                            ilst(6).l = intersect(x2,intersect(y1,z2));
                            ilst(7).l = intersect(x1,intersect(y2,z2));
                            ilst(8).l = intersect(x2,intersect(y2,z2));
                            
                            idx = [];
                            
                            % do parallel re-checking for all points in each subvolume
                            for i = 1:8                    
                                if(~isempty(ilst(i).l))
                                    idx = ilst(i).l;
                                    li = sub2ind([width height depth],x(idx),y(idx),z(idx));
                                    skel(li)=0; % remove points
                                    nh = logical(pk_get_nh(skel,li));
                                    di_rc = find(~p_is_simple(nh));
                                    if(~isempty(di_rc)) % if topology changed: revert
                                        skel(li(di_rc))=1;
                                    else
                                        noChange = false; % at least one voxel removed
                                    end
                                end
                            end
                        end
                    end
                    
                    if( noChange )
                        unchangedBorders = unchangedBorders + 1;
                    end
                    fprintf('\b\b\b\b\b\b\b\b\b\b\b\b\b\b\b\b\b\b\b');
                    fprintf('removed %3d%% voxels',round(100*(l_orig-length(find(skel(:))))/l_orig));
                    
                end
            end
            
            fprintf('\n');
            
            % get rid of padded zeros
            skel = skel(2:end-1,2:end-1,2:end-1);
            
            app.handles.Y = skel;
        end
        
        function centerline(app)
            % The function produces a centerline representation by labeling skeleton
            % points as either,
            % 1. end points = 1
            % 2. middle/branch points = 2
            % 3. junction points = 3
            %
            % The function initially labels all points according to the list above, and
            % then remove spurs shorter than a specified length.
            %
            % The labels are stored both in matrix form (res*res*res), and in list
            % form, List(x,y,z,label).
            %
            % The function then proceeds iteratively with a new labeling, followed by
            % removal of additional spurs until no change longer occur in the skeleton.
            %
            % INPUT
            % 1. Y is centerline in binary form
            % 2. VMEAN.
            % 3. spurLenght is the length of spurs to be removed
            % 4. sortingCriteria is criteria for branch sorting
            %
            % OUTPUT
            % 1. CL is centerline with spurs removed and points classified
            % 2. branchMat is branch indices & labels in matrix form
            % 3. branchList is branch indices & labels in list form
            % 4. branchTextList is accompaning text (number labels)
            % 5. junctionMat is junction indices & labels in matrix form
            % 6. junctionList is junction indices & labels in list form
            %
            % Author: Erik Spaak
            
            % function [CL, branchMat, branchList, branchTextList, junctionMat, junctionList] = centerline(Y, vMean, spurLength, sortingCriteria)
            
            modified = 1;
            Niter = 0;
            
            % dilate & erode to "delete" big junctions
            if 1
                se = ones(3,3,3);
                %se(1) = 0; se(3) = 0; se(7) = 0; se(9) = 0; se(19) = 0; se(21) = 0; se(25) = 0; se(27) = 0;
                X = imdilate(Y,se);
                %X2 = imdilate(X,se);
                %X3 = imdilate(X2,se);
                %Y3 = imerode(X3,se);
                %Y2 = imerode(Y3,se);
                Y = imerode(X,se);
                
            %     disp('calculated dilated/eroded skeleton')
                
                Y = thinning(Y);
            end
            
            CL = 2*Y;
            
            while modified > 0  && Niter < 20% do until convergence
                
                Niter = Niter + 1;
                
            %     disp(['CL-summa ' num2str(sum(sum(sum(CL))))])
                
                % deletion of branches
                if Niter > 1    % do after first iteration
                    
                    modified = 0;
                    
                    uniqueBranchLabels = unique(branchList(:,4));
                    for i = 1:length(uniqueBranchLabels)
            
                        currentBranchLabel = uniqueBranchLabels(i);
                        currentBranchIndices = find(branchList(:,4) == currentBranchLabel);
                        currentBranchLength = length(currentBranchIndices);
                        
                        connectedToJunctions = 0;
                        
                        for j = currentBranchIndices'
                            x0 = branchList(j,1); y0 = branchList(j,2); z0 = branchList(j,3);
                            connectedToJunctions = [connectedToJunctions; unique(junctionMat(x0-1:x0+1, y0-1:y0+1, z0-1:z0+1))];
                        end
            
                        connectedToJunctions = unique(connectedToJunctions);
                        
                        % delete branch if too short and not between two differently labeled junction points
                        if (currentBranchLength) < spurLength && (length(connectedToJunctions) < 3)
            
                            for j = currentBranchIndices'
                                CL(branchList(j,1), branchList(j,2), branchList(j,3)) = 0;
                            end
            
                            modified = modified + 1;
            
                        end
                    end
            
                end
                
            %     disp(['Number of deleted branches: ' num2str(modified-1)])
            %     disp(['CL-summa ' num2str(sum(sum(sum(CL))))])
                
                % classify skeleton points
                CL = 2*logical(CL);
                CLindices = find(CL);
            
                for i = 1:length(CLindices)
            
                    [x0, y0, z0] = ind2sub([res res res], CLindices(i));
            
                    % 26-neighborhood sum
                    neighSum = sum(sum(sum(logical(CL(x0-1:x0+1, y0-1:y0+1, z0-1:z0+1)))));
            
                    % mark junction points
                    
                    if neighSum > 3
                        CL(CLindices(i)) = sortingCriteria;
                    end
            
            %         % mark endpoints
            %         if neighSum < 3
            %             CL(CLindices(i)) = 1;
            %         end
                end
            
                % list junction points
                junctionIndices = find(CL == 3);
                [x0, y0, z0] = ind2sub([res res res], junctionIndices);
                junctionMat = zeros(res,res,res);    % label matrix
                junctionList = [x0 y0 z0 zeros(length(x0), 1)];    % label vector
            
                % label junctions
            
                label = 0;
                for i = 1:length(x0)
                    
                    if junctionList(i,4) == 0
                        label = label + 1;
                        junctionMat(x0(i), y0(i), z0(i)) = label;
                        junctionList(i,4) = label;
            
                        investigatePointsList = [x0(i) y0(i) z0(i)];
            
                        labeled = 1;
            
                        while labeled > 0   % while still collecting points under this label
                            
                            labeled = 0;
                            newInvestigativePointsList = [];
                            
                            for j = 1:length(investigatePointsList(:,1))
            
                                x1 = investigatePointsList(j,1);
                                y1 = investigatePointsList(j,2);
                                z1 = investigatePointsList(j,3);
            
                                % collect 26-neighborhoods
                                label26 = junctionMat(x1-1:x1+1, y1-1:y1+1, z1-1:z1+1);
                                antiLabel26 = logical((logical(label26) - 1));
                                CL26 = CL(x1-1:x1+1, y1-1:y1+1, z1-1:z1+1);
            
                                neigh = find(CL26.*antiLabel26 == 3);   % find neighboring middle points not labeled
                                [x2, y2, z2] = ind2sub([3 3 3], neigh);
            
                                x3 = x1 + x2 - 2;
                                y3 = y1 + y2 - 2;
                                z3 = z1 + z2 - 2;
            
                                for k = 1:length(x3)
                                    
                                    junctionMat(x3(k), y3(k), z3(k)) = label;
                                    
                                    a = find(junctionList(:,1) == x3(k));
                                    b = find(junctionList(:,2) == y3(k));
                                    c = find(junctionList(:,3) == z3(k));
                                    d = intersect(a,b);
                                    e = intersect(c,d);
            
                                    junctionList(e,4) = label;
                                    
                                    labeled = labeled + 1;  % count how many points collected under current label
                                    
                                end
                                
                                newInvestigativePointsList = [newInvestigativePointsList; x3 y3 z3];
                                
                            end
            
                            investigatePointsList = newInvestigativePointsList;
                            
                        end
                        
                    end
                    
                end
            
                % list middle points
                branchIndices = find(CL == 2);
                [x0, y0, z0] = ind2sub([res res res], branchIndices);
                branchMat = zeros(res,res,res);    % label matrix
                branchList = [x0 y0 z0 zeros(length(x0), 2)];    % label vector
            
                % label branches
            
                branchTextList = zeros(0,4);
                label = 0;
                for i = 1:length(x0)
                    
                    if branchList(i,4) == 0
                        
                        label = label + 1;
                        branchMat(x0(i), y0(i), z0(i)) = label;
                        branchList(i,4) = label;
                        
                        branchTextList = [branchTextList; x0(i) y0(i) z0(i) label]; % create a textlist
                        
                        investigatePointsList = [x0(i) y0(i) z0(i)];
            
                        labeled = 1;
                             incrementer = 0;    
                        while labeled > 0   % while still collecting points under this label
                            
                            labeled = 0;
                            newInvestigativePointsList = [];
            
                       
                            for j = 1:length(investigatePointsList(:,1))
            
                                x1 = investigatePointsList(j,1);
                                y1 = investigatePointsList(j,2);
                                z1 = investigatePointsList(j,3);
            
                                % collect 26-neighborhoods
                                label26 = branchMat(x1-1:x1+1, y1-1:y1+1, z1-1:z1+1);
                                antiLabel26 = logical((logical(label26) - 1));
                                CL26 = CL(x1-1:x1+1, y1-1:y1+1, z1-1:z1+1);
            
                                neigh = find(CL26.*antiLabel26 == 2);   % find neighboring middle points not labeled
                                [x2, y2, z2] = ind2sub([3 3 3], neigh);
                                
                                x3 = x1 + x2 - 2;
                                y3 = y1 + y2 - 2;
                                z3 = z1 + z2 - 2;
            
                                incrementer = incrementer + 1;
                                
                                for k = 1:length(x3)
                                    
                                    branchMat(x3(k), y3(k), z3(k)) = label;
                                    
                                    a = find(branchList(:,1) == x3(k));
                                    b = find(branchList(:,2) == y3(k));
                                    c = find(branchList(:,3) == z3(k));
                                    d = intersect(a,b);
                                    e = intersect(c,d);
            
                                    branchList(e,4) = label;
                                    
                                    % sorting
                                    aa = find(branchList(:,1) == x1);
                                    bb = find(branchList(:,2) == y1);
                                    cc = find(branchList(:,3) == z1);
                                    dd = intersect(aa,bb);
                                    ee = intersect(cc,dd);
            
                                    if branchList(ee,5) == 0 && k == 1
                                        
                                        branchList(e,5) = 1;
                                        
                                    elseif branchList(ee,5) == 0 && k == 2
                                        
                                        branchList(e,5) = -1;  
                                        
                                    elseif branchList(ee,5) > 0
                                        
                                        branchList(e,5) = branchList(ee,5) + 1;
                                        
                                    elseif branchList(ee,5) < 0
                                        
                                        branchList(e,5) = branchList(ee,5) - 1;
                                    end
            
            
                                    labeled = labeled + 1;  % count how many points collected under current label
                                    
                                end
                                
                                newInvestigativePointsList = [newInvestigativePointsList; x3 y3 z3];
                                
                            end
                            
                            investigatePointsList = newInvestigativePointsList;
                            
                        end
               
                    end
                    
                end
               
            end
            
            %%
            % sort the branchList so that
            % 1. all the same labels are connected along the rows
            % 2. low -> high row index is in the same direction as the flow
            labels = unique(branchList(:,4));
            branchListSorted = zeros(0,5);
            
            beginSegment = 8;
            
            for i = 1:length(labels)
            
                % find branch
                branchActual = branchList(branchList(:,4) == labels(i), :);
                branchActual = sortrows(branchActual, 5);
            
                % check if a -> b is in the direction of the flow...
                if size(branchActual,1) < beginSegment
                    
                    v0x = 0; v0y = 0; v0z = 0;
                    for j = 1:size(branchActual, 1)
                        v0x = v0x + vMean(branchActual(j,1), branchActual(j,2), branchActual(j,3), 1);
                        v0y = v0y + vMean(branchActual(j,1), branchActual(j,2), branchActual(j,3), 2);
                        v0z = v0z + vMean(branchActual(j,1), branchActual(j,2), branchActual(j,3), 3);
                    end
                    
                    isReverse = dot(double(branchActual(end, 1:3) - branchActual(1, 1:3)), double([v0x v0y v0z]));
                else
                    
                    v0x = 0; v0y = 0; v0z = 0;
                    for j = 1:beginSegment%size(branchActual, 1)
                        v0x = v0x + vMean(branchActual(j,1), branchActual(j,2), branchActual(j,3), 1);
                        v0y = v0y + vMean(branchActual(j,1), branchActual(j,2), branchActual(j,3), 2);
                        v0z = v0z + vMean(branchActual(j,1), branchActual(j,2), branchActual(j,3), 3);
                    end
                    
                    isReverse = dot(double(branchActual(beginSegment, 1:3) - branchActual(1, 1:3)), double([v0x v0y v0z]));
                end
            
                % ...if not, reverse
                if isReverse < 0
                    branchActual = flipud(branchActual);
                end
            
                branchListSorted = [branchListSorted; branchActual];
            
            end
            
            branchList = branchListSorted;
            
            for n = 1:max(branchList(:,4))
                branchActual = branchList(branchList(:,4)==n,:);
                if size(branchActual,1)<9 % Could be Spurlength
                    branchList(branchList(:,4)==n,:) = [];
                else
                end
            end
            
            app.handles.CL = CL;
            app.handles.branchMat = branchMat;
            app.handles.branchList = branchList;
            app.handles.branchTextList = branchTextList;
            app.handles.junctionMat = junctionMat;
            app.handles.junctionList = junctionList;
        end
    end
    
    % vessel segmentation
    methods (Access = private)
        function select_vessel(app)
            res = app.handles.res;
            MAG = app.handles.MAG;
            segment = app.handles.segment;
            branchMat = app.handles.branchMat;
            branchList = app.handles.branchList;
                       
            if isfield(app.handles, 'vessel')
                app.handles = rmfield(app.handles, 'vessel');
            end
            
            MAGmax = max(max(max(MAG)));
            MAGr = im2uint8(MAG/MAGmax);
            MAGg = im2uint8(MAG/MAGmax);
            MAGb = im2uint8(MAG/MAGmax);
            cm = colormap(jet(255)); % creates fig
            delete(gcf);
            
            counter = 0;
            % result = 5;
            % while result ~= 2
            MAGr(find(segment)) = cm(255,1)*255;
            MAGg(find(segment)) = cm(150,2)*150;
            MAGb(find(segment)) = cm(1,3)*1;
            branchNum = 0;
            
            while branchNum == 0
                % opens radiologic images
                [x, y, z] = radiologic_images(MAGr(end:-1:1,:,end:-1:1), MAGg(end:-1:1,:,end:-1:1), MAGb(end:-1:1,:,end:-1:1), [], res);

                % finds closest point in branchMat then uses that value for vessel selection
                Points = regionprops(branchMat>0,'PixelList');
                Points = struct2cell(Points);
                Points = cell2mat(Points');
            
                distance = sqrt(sum((bsxfun(@minus,Points,[y,x,z])).^2,2));
                Val = find(distance == min(distance));
                Points = Points(Val(1),:);
                branchNum = branchMat(Points(2),Points(1),Points(3));
            end
            
            counter = counter + 1;
            vessel(counter) = branchNum;
            
            disp('Found vessel!');
        end
              
        function segement_vessel(app)
            disp('Segmenting Vessel...');
            indices = 0; 
            indexes = 0;
            for i = 1:length(vessel)
                indices = vertcat(indices,find(branchList(:,4) == vessel(i)));
                indexes = vertcat(indexes,find(branchMat == vessel(i)));
            end
            indices(1) = []; 
            indexes(1) = [];
            
            branchActual = zeros(numel(indices),3);
            branchActual(:,1) = branchList(indices,1);
            branchActual(:,2) = branchList(indices,2);
            branchActual(:,3) = branchList(indices,3);
            
            % Image dilate and multiply by mask to extract entire vessel length
            branchMat2 = zeros(res,res,res);
            % indexes = (branchMat == vessel(:));
            branchMat2(indexes) = 1;
            I1 = imdilate(branchMat2,ones(7,7,7));
            
            timeMIPvessel = I1.*segment;
            timeMIPvessel(timeMIPvessel~=0) = 1;
            
            % add variables to handle
            app.handles.timeMIPvessel = timeMIPvessel;
            app.handles.branchActual = branchActual;
            app.handles.branchNum = branchNum;
            app.handles.vessel = vessel;
            % app.handles.distance = distance;
            
            disp('Finished segmenting vessel');
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.centerline_tool_main.Name = 'Centerline Main Window';
        end

        % Close request function: centerline_tool_main
        function centerline_tool_mainCloseRequest(app, event)
            delete(app);
            % delete children GUIs
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            get_vipr_directory(app);
            if app.handles.directory == 0
                warning('No folder selected');
                return
            end    
            disp('Loading data...');
            load_vipr_data(app);
            poly_correction(app);
            view_angio(app);
            disp('Load Data finished');
        end

        % Button pushed function: DrawROIButton
        function DrawROIButtonPushed(app, event)
            % call ROI gui
            warning('Function has not yet been programmed');
        end

        % Button pushed function: ViewParametricMapButton
        function ViewParametricMapButtonPushed(app, event)
            % paramMap(app.handles);
            warning('Function has not yet been programmed');
        end

        % Button pushed function: FeatureExtractionButton
        function FeatureExtractionButtonPushed(app, event)
            % Calculate phase coherence and extract centerline for the vascular tree
            % specify sortingCriteria as either
            % = 2 to get all branches connected to each other sorting (few branches)
            % = 3 to get branch by branch sorting (many branches)
            app.handles.sortingCriteria = 3;
            app.handles.spurLength = 8;
            
            % vascularTreeReconstr
            % 1. 'coarse'. Uses a PCthreshDev of 0.25 and a box filter to remove noise
            %   in the reconstructed vascular tree. Misses some vessels.
            % 2. 'fine' Uses a PCthreshDev of 0.3 and no filter. Noisier than 'coarse',
            %   but finds small vessels more easily.
            app.handles.vascularTreeReconstr = 'coarse';
            
            skeleton_3D(app.handles.segment);
            centerline(app.handles);
            disp('Finished Feature Extraction');
        end

        % Button pushed function: SelectVesselandSegmentButton
        function SelectVesselandSegmentButtonPushed(app, event)
            select_vessel(app);
            segement_vessel(app);
            app.Vessel3D_app = Vessel3D_gui(app, app.handles);
        end

        % Button pushed function: ReorientButton
        function ReorientButtonPushed(app, event)
            view(app.Vasculature3DAxes, [-.5 0 0]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.centerline_tool_main = uifigure('Visible', 'off');
            app.centerline_tool_main.Name = 'UI Figure';
            app.centerline_tool_main.CloseRequestFcn = createCallbackFcn(app, @centerline_tool_mainCloseRequest, true);
            app.LoadDataButton = uibutton(app.centerline_tool_main, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.DataDirectoryLabel = uilabel(app.centerline_tool_main);
            app.DataDirectoryLabel.Text = 'Data Directory';
            app.Vasculature3DAxes = uiaxes(app.centerline_tool_main);
            title(app.Vasculature3DAxes, '3D Vasculature')
            xlabel(app.Vasculature3DAxes, '')
            ylabel(app.Vasculature3DAxes, '')
            app.DrawROIButton = uibutton(app.centerline_tool_main, 'push');
            app.DrawROIButton.ButtonPushedFcn = createCallbackFcn(app, @DrawROIButtonPushed, true);
            app.DrawROIButton.Text = 'Draw ROI';
            app.ViewParametricMapButton = uibutton(app.centerline_tool_main, 'push');
            app.ViewParametricMapButton.ButtonPushedFcn = createCallbackFcn(app, @ViewParametricMapButtonPushed, true);
            app.ViewParametricMapButton.Text = 'View Parametric Map';
            app.FeatureExtractionButton = uibutton(app.centerline_tool_main, 'push');
            app.FeatureExtractionButton.ButtonPushedFcn = createCallbackFcn(app, @FeatureExtractionButtonPushed, true);
            app.FeatureExtractionButton.Text = 'Feature Extraction';
            app.SelectVesselandSegmentButton = uibutton(app.centerline_tool_main, 'push');
            app.SelectVesselandSegmentButton.ButtonPushedFcn = createCallbackFcn(app, @SelectVesselandSegmentButtonPushed, true);
            app.SelectVesselandSegmentButton.Text = 'Select Vessel and Segment';
            app.ReorientButton = uibutton(app.centerline_tool_main, 'push');
            app.ReorientButton.ButtonPushedFcn = createCallbackFcn(app, @ReorientButtonPushed, true);
            app.ReorientButton.Text = 'Reorient';
            % Show the figure after all components are created
            app.centerline_tool_main.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = centerline_tool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.centerline_tool_main)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.centerline_tool_main)
        end
    end
end