classdef parameter_plot_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        parameter_plot_fig           matlab.ui.Figure
        axes1                        matlab.ui.control.UIAxes
        WindowSizeSpinnerLabel       matlab.ui.control.Label
        WindowSizeSpinner            matlab.ui.control.Spinner
        VoxelLowerBoundSpinnerLabel  matlab.ui.control.Label
        VoxelLowerBoundSpinner       matlab.ui.control.Spinner
        RecommendedDonotusethesevoxelsLabel  matlab.ui.control.Label
        LegendSwitchLabel            matlab.ui.control.Label
        LegendSwitch                 matlab.ui.control.Switch
        VesselNamesaveasLabel        matlab.ui.control.Label
        VesselNameDropDown           matlab.ui.control.DropDown
        SaveDataStartSpinnerLabel    matlab.ui.control.Label
        SaveDataStartSpinner         matlab.ui.control.Spinner
        SaveDataEndLabel             matlab.ui.control.Label
        SaveDataEndSpinner           matlab.ui.control.Spinner
        axes2                        matlab.ui.control.UIAxes
        axes3                        matlab.ui.control.UIAxes
        axes4                        matlab.ui.control.UIAxes
        axes5                        matlab.ui.control.UIAxes
        axes6                        matlab.ui.control.UIAxes
        axes7                        matlab.ui.control.UIAxes
        axes8                        matlab.ui.control.UIAxes
        VarianceLabel1               matlab.ui.control.Label
        VarianceLabel2               matlab.ui.control.Label
        VarianceLabel3               matlab.ui.control.Label
        VarianceLabel4               matlab.ui.control.Label
        VarianceLabel5               matlab.ui.control.Label
        VarianceLabel6               matlab.ui.control.Label
        VarianceLabel7               matlab.ui.control.Label
        VarianceLabel8               matlab.ui.control.Label
        SaveButton                   matlab.ui.control.Button
    end

    % other properties
    properties (Access = private)
        Vessel3D_app;
        
        % structure for storing variables
        handles; 
        
        % shading patch properties
        patch1;
        patch2;
        patch3;
        patch4;
        patch5;
        patch6;
        patch7;
        
        % toolbar
        tb1;
        tb2;
        tb3;
        tb4;
        tb5;
        tb6;
        tb7;
        tb8;
        
        % legend
        lgd;
        
    end
    
    % functions
    methods (Access = private)
        
        %% for testing only!!!
        function test_values(app)
            app.handles.values.area = cell2mat(evalin('base', 'area'));
            app.handles.values.diam = cell2mat(evalin('base', 'diam'));
            app.handles.values.meanVel = cell2mat(evalin('base', 'meanVel'));
            app.handles.values.maxVel = cell2mat(evalin('base', 'maxVel'));
            app.handles.values.flowPerHeartCycle = cell2mat(evalin('base', 'flowPerHeartCycle'));
            app.handles.values.wss_simple_avg = cell2mat(evalin('base', 'wss_simple_avg'));
            app.handles.values.PI = cell2mat(evalin('base', 'PI'));
            app.handles.values.flowPulsatile = evalin('base', 'flowPulsatile');
            app.handles.values.cardiac_time = evalin('base', 'cardiac_time'); 
            app.handles.values.upper_bound = evalin('base', 'upper_bound');
        end
        %%
        
        %% time average plots
        function time_avg_plotter(app)    
            x = [1:length(app.handles.values.area)]; % i.e. voxel count
            
            % area plot
            y_data = app.handles.values.area;
            plot(x, y_data, 'Parent', app.axes1);
            app.axes1.XLim = [1 max(x)];
            [min_y_value, max_y_value] = min_max_y(app, y_data);
            app.axes1.YLim = [min_y_value max_y_value];
            app.axes1.Title.String = 'Area';
            app.axes1.XLabel.String = 'Centerline Point';
            app.axes1.YLabel.String = 'cm^2';
            
            % diameter plot
            y_data = app.handles.values.diam;
            plot(x, y_data, 'Parent', app.axes2);
            app.axes2.XLim = [1 max(x)];
            [min_y_value, max_y_value] = min_max_y(app, y_data);
            app.axes2.YLim = [min_y_value max_y_value];
            app.axes2.Title.String = 'Diameter';
            app.axes2.XLabel.String = 'Centerline Point';
            app.axes2.YLabel.String = 'cm';
            
            % mean velocity plot
            y_data = app.handles.values.meanVel;
            plot(x, y_data, 'Parent', app.axes3);
            app.axes3.XLim = [1 max(x)];
            [min_y_value, max_y_value] = min_max_y(app, y_data);
            app.axes3.YLim = [min_y_value max_y_value];
            app.axes3.Title.String = 'Mean Velocity';
            app.axes3.XLabel.String = 'Centerline Point';
            app.axes3.YLabel.String = 'cm/s';
            
            % max velocity plot
            y_data = app.handles.values.maxVel;
            plot(x, y_data, 'Parent', app.axes4);
            app.axes4.XLim = [1 max(x)];
            [min_y_value, max_y_value] = min_max_y(app, y_data);
            app.axes4.YLim = [min_y_value max_y_value];
            app.axes4.Title.String = 'Max Velocity';
            app.axes4.XLabel.String = 'Centerline Point';
            app.axes4.YLabel.String = 'cm/s';
            
            % flow per heartbeat plot
            y_data = app.handles.values.flowPerHeartCycle;
            plot(x, y_data, 'Parent', app.axes5);
            app.axes5.XLim = [1 max(x)];
            [min_y_value, max_y_value] = min_max_y(app, y_data);
            app.axes5.YLim = [min_y_value max_y_value];
            app.axes5.Title.String = 'Flow per Beat';
            app.axes5.XLabel.String = 'Centerline Point';
            app.axes5.YLabel.String = 'mL';
            
            % wss plot
            y_data = app.handles.values.wss_simple_avg;
            plot(x, y_data, 'Parent', app.axes6);
            app.axes6.XLim = [1 max(x)];
            [min_y_value, max_y_value] = min_max_y(app, y_data);
            app.axes6.YLim = [min_y_value max_y_value];
            app.axes6.Title.String = 'Wall Shear Strss';
            app.axes6.XLabel.String = 'Centerline Point';
            app.axes6.YLabel.String = 'Pa';
            
            % PI plot
            y_data = app.handles.values.PI;
            plot(x, y_data, 'Parent', app.axes7);
            app.axes7.XLim = [1 max(x)];
            [min_y_value, max_y_value] = min_max_y(app, y_data);
            app.axes7.YLim = [min_y_value max_y_value];
            app.axes7.Title.String = 'Pulsatility Index';
            app.axes7.XLabel.String = 'Centerline Point';
            app.axes7.YLabel.String = 'a.u.';
            
            %% get min and max y-values for y-axis scaling
            function [min_y, max_y] = min_max_y(app, y_data)
                min_y= min(y_data) * 0.90; % y-limit 10% < min y-value
                max_y = max(y_data) * 1.10; % y-limit 10% > max y-value
            end
            
        end

        %% pulsatile flow (i.e. time resolved) plot
        function time_resolved_plotter(app) 
            %%% TODO 05-13-2020: add x-axis tick marks
            cla(app.axes8);
            lower_bound = app.VoxelLowerBoundSpinner.Value;
            window = app.WindowSizeSpinner.Value;
            x = app.handles.timeres/1000*linspace(1, app.handles.nframes, app.handles.nframes);
            
            hold(app.axes8, 'on');
            for y = lower_bound:lower_bound + window - 1
                 y_data = app.handles.values.flowPulsatile(y,:);
                 plot(x, smooth(y_data), 'Parent', app.axes8);
            end
            hold(app.axes8, 'off');
            app.axes8.XTick = x;
            app.axes8.XTickLabelRotation = 45; 
            app.axes8.Title.String = 'Pulsatile Flow';
            app.axes8.XLabel.String = 'Cardiac Time (s)';
            app.axes8.YLabel.String = 'mL/s';
            update_legend(app);
        end
        
        %% add window shading
        function shading(app)
            lower_bound = app.VoxelLowerBoundSpinner.Value;
            upper_bound = length(app.handles.values.area);
            window = app.WindowSizeSpinner.Value;
            
            if window ~= upper_bound
                x_data = [lower_bound lower_bound+window lower_bound+window lower_bound];
                y_limit = 0;
                color = [0.3010 0.7450 0.9330]; % light blue
                alpha = 0.2;
                edge_color = 'None';
                
                % area plot
                app.patch1 = patch(app.axes1);
                app.patch1.XData = x_data;
                y_limit = max(app.axes1.YLim);
                app.patch1.YData = get_y_data(y_limit);
                app.patch1.FaceColor = color;
                app.patch1.FaceAlpha = alpha;
                app.patch1.EdgeColor = edge_color;
                
                % diameter plot
                app.patch2 = patch(app.axes2);
                app.patch2.XData = x_data;
                y_limit = max(app.axes2.YLim);
                app.patch2.YData = get_y_data(y_limit);
                app.patch2.FaceColor = color;
                app.patch2.FaceAlpha = alpha;
                app.patch2.EdgeColor = edge_color;
                
                % mean velocity
                app.patch3 = patch(app.axes3);
                app.patch3.XData = x_data;
                y_limit = max(app.axes3.YLim);
                app.patch3.YData = get_y_data(y_limit);
                app.patch3.FaceColor = color;
                app.patch3.FaceAlpha = alpha;
                app.patch3.EdgeColor = edge_color;
                
                % max velocity
                app.patch4 = patch(app.axes4);
                app.patch4.XData = x_data;
                y_limit = max(app.axes4.YLim);
                app.patch4.YData = get_y_data(y_limit);
                app.patch4.FaceColor = color;
                app.patch4.FaceAlpha = alpha;
                app.patch4.EdgeColor = edge_color;
                
                % flow/beat
                app.patch5 = patch(app.axes5);
                app.patch5.XData = x_data;
                y_limit = max(app.axes5.YLim);
                app.patch5.YData = get_y_data(y_limit);
                app.patch5.FaceColor = color;
                app.patch5.FaceAlpha = alpha;
                app.patch5.EdgeColor = edge_color;
                
                % wss
                app.patch6 = patch(app.axes6);
                app.patch6.XData = x_data;
                y_limit = max(app.axes6.YLim);
                app.patch6.YData = get_y_data(y_limit);
                app.patch6.FaceColor = color;
                app.patch6.FaceAlpha = alpha;
                app.patch6.EdgeColor = edge_color;
                
                % PI
                app.patch7 = patch(app.axes7);
                app.patch7.XData = x_data;
                y_limit = max(app.axes7.YLim);
                app.patch7.YData = get_y_data(y_limit);
                app.patch7.FaceColor = color;
                app.patch7.FaceAlpha = alpha;
                app.patch7.EdgeColor = edge_color;
                
            end
            
            function y_data = get_y_data(y_limit)
                y_data = [0 0 y_limit y_limit];
            end
            
        end
        
        %% delete shading
        function delete_shading(app)
            try
                delete(app.patch1);
                delete(app.patch2);
                delete(app.patch3);
                delete(app.patch4);
                delete(app.patch5);
                delete(app.patch6);
                delete(app.patch7);
            catch
            end
        end
        
        %% update variance textboxes
        function update_variance(app)
            
            % area plot
            y_data = app.handles.values.area';
            window_variance = voxel_window_variance(app, y_data);
            app.VarianceLabel1.Text = ['Variance: ', num2str(window_variance)];
            
            % diameter plot
            y_data = app.handles.values.diam';
            window_variance = voxel_window_variance(app, y_data);
            app.VarianceLabel2.Text = ['Variance: ', num2str(window_variance)];
            
            % mean velocity plot
            y_data = app.handles.values.meanVel';
            window_variance = voxel_window_variance(app, y_data);
            app.VarianceLabel3.Text = ['Variance: ', num2str(window_variance)];
            
            % max velocity plot
            y_data = app.handles.values.maxVel';
            window_variance = voxel_window_variance(app, y_data);
            app.VarianceLabel4.Text = ['Variance: ', num2str(window_variance)];
            
            % flow/beat plot
            y_data = app.handles.values.flowPerHeartCycle';
            window_variance = voxel_window_variance(app, y_data);
            app.VarianceLabel5.Text = ['Variance: ', num2str(window_variance)];
            
            % wss plot
            y_data = app.handles.values.wss_simple_avg';
            window_variance = voxel_window_variance(app, y_data);
            app.VarianceLabel6.Text = ['Variance: ', num2str(window_variance)];
            
            % PI plot
            y_data = app.handles.values.PI';
            window_variance = voxel_window_variance(app, y_data);
            app.VarianceLabel7.Text = ['Variance: ', num2str(window_variance)];
            
            % pulsatile flow plot
            y_data = app.handles.values.flowPulsatile;
            window_variance = voxel_window_variance(app, y_data);
            app.VarianceLabel8.Text = ['Variance: ', num2str(window_variance)];

            %% mean variance calculation
            function window_variance = voxel_window_variance(app, y_data)
                lower_bound = app.VoxelLowerBoundSpinner.Value;
                window = app.WindowSizeSpinner.Value;
                window_segment = y_data(lower_bound:lower_bound + window - 1,:);
                window_variance = var(window_segment, 0, 1);
                sum_of_variance = sum(window_variance);
                window_variance = mean(sum_of_variance);
            end 
        end
    
        function update_legend(app)
            if app.WindowSizeSpinner.Value <= 5 && strcmp(app.LegendSwitch.Value, 'On')
                lower_bound = app.VoxelLowerBoundSpinner.Value;
                window = app.WindowSizeSpinner.Value;
                voxel_numbers = [lower_bound:lower_bound + window - 1];
                app.lgd = legend(app.axes8);
                app.lgd.String = append('Voxel ', string(voxel_numbers));
                app.lgd.Units = 'Normalized';
                app.lgd.Position = [0.875 0.4 0.1 0.2];
            else
                delete(app.lgd);
            end
        end
        
        function save_plots(app)
            %%% make images directory if it doesn't exist
            if ~exist(fullfile(app.handles.directory, 'data', 'images')) == 1
                mkdir(fullfile(app.handles.directory, 'data', 'images'))
            end
            
            images_dir = fullfile(app.handles.directory, 'data', 'images');
            
            %%% initial variables
            vessel = strrep(lower(app.handles.vessel_name), ' ', '_');
            file_ext = '.png';
            disp_format = '    Saved %s plot\n';
            
            %%% save vessel segment w/ and w/o isolation??
            fname_suffix = strcat('_voxel', num2str(app.handles.lower_bound), '_window', num2str(app.handles.window));
            vessel_seg = copyUIAxes(app.Vessel3D_app.VesselSegAxes);
            fname = fullfile(images_dir, [vessel, fname_suffix, file_ext]);
            saveas(vessel_seg.axes, fname);
            fprintf('    Saved %s image\n', 'vessel segment');
            
            %%% save parameter plots
            % R2019b cannot save plots from UI figures (stupid)
            % need to copy axis (i.e. plot) to new figure then save
            % could probably make this into a loop but idk how and I'm
            % running out of time to get this done 05/21/2020
            
            %% area
            fig = copyUIAxes(app.axes1);
            
            % reshape figure window
            fig.figure.Position(3) = app.axes1.Position(3) * 1.15; % make fig 15% wider than axis
            fig.figure.Position(4) = app.axes1.Position(4) * 1.50; % make fig 50% taller than axis
            
            % reshape figure axes
            fig.axes.Units = 'Pixels';
            fig.axes.Position(3:4) = app.axes1.Position(3:4);
            saveas(fig.axes, fullfile(images_dir, [vessel, '_area', file_ext]));
            delete(fig.figure);
            fprintf(disp_format, 'area');
            
            %% diameter
            fig = copyUIAxes(app.axes2);
            
            % reshape figure window
            fig.figure.Position(3) = app.axes2.Position(3) * 1.15; % make fig 15% wider than axis
            fig.figure.Position(4) = app.axes2.Position(4) * 1.50; % make fig 50% taller than axis
            
            % reshape figure axes
            fig.axes.Units = 'Pixels';
            fig.axes.Position(3:4) = app.axes2.Position(3:4);
            saveas(fig.axes, fullfile(images_dir, [vessel, '_diameter', file_ext]));
            delete(fig.figure);
            fprintf(disp_format, 'diameter');
            
            %% mean velocity
            fig = copyUIAxes(app.axes3);
            
            % reshape figure window
            fig.figure.Position(3) = app.axes3.Position(3) * 1.15; % make fig 15% wider than axis
            fig.figure.Position(4) = app.axes3.Position(4) * 1.50; % make fig 50% taller than axis
            
            % reshape figure axes
            fig.axes.Units = 'Pixels';
            fig.axes.Position(3:4) = app.axes3.Position(3:4);
            saveas(fig.axes, fullfile(images_dir, [vessel, '_mean_velocity', file_ext]));
            delete(fig.figure);
            fprintf(disp_format, 'mean velocity');
            
            %% max velocity
            fig = copyUIAxes(app.axes4);
            
            % reshape figure window
            fig.figure.Position(3) = app.axes4.Position(3) * 1.15; % make fig 15% wider than axis
            fig.figure.Position(4) = app.axes4.Position(4) * 1.50; % make fig 50% taller than axis
            
            % reshape figure axes
            fig.axes.Units = 'Pixels';
            fig.axes.Position(3:4) = app.axes4.Position(3:4);
            saveas(fig.axes, fullfile(images_dir, [vessel, '_max_velocity', file_ext]));
            delete(fig.figure);
            fprintf(disp_format, 'max velocity');
            
            %% flow/beat
            fig = copyUIAxes(app.axes5);
            
            % reshape figure window
            fig.figure.Position(3) = app.axes5.Position(3) * 1.15; % make fig 15% wider than axis
            fig.figure.Position(4) = app.axes5.Position(4) * 1.50; % make fig 50% taller than axis
            
            % reshape figure axes
            fig.axes.Units = 'Pixels';
            fig.axes.Position(3:4) = app.axes5.Position(3:4);
            saveas(fig.axes, fullfile(images_dir, [vessel, '_flowPerBeat', file_ext]));
            delete(fig.figure);
            fprintf(disp_format, 'flow/beat');
            
            %% wss
            fig = copyUIAxes(app.axes6);
            
            % reshape figure window
            fig.figure.Position(3) = app.axes6.Position(3) * 1.15; % make fig 15% wider than axis
            fig.figure.Position(4) = app.axes6.Position(4) * 1.50; % make fig 50% taller than axis
            
            % reshape figure axes
            fig.axes.Units = 'Pixels';
            fig.axes.Position(3:4) = app.axes6.Position(3:4);
            saveas(fig.axes, fullfile(images_dir, [vessel, '_wss', file_ext]));
            delete(fig.figure);
            fprintf(disp_format, 'WSS');
            
            %% PI
            fig = copyUIAxes(app.axes7);
            
            % reshape figure window
            fig.figure.Position(3) = app.axes7.Position(3) * 1.15; % make fig 15% wider than axis
            fig.figure.Position(4) = app.axes7.Position(4) * 1.50; % make fig 50% taller than axis
            
            % reshape figure axes
            fig.axes.Units = 'Pixels';
            fig.axes.Position(3:4) = app.axes7.Position(3:4);
            saveas(fig.axes, fullfile(images_dir, [vessel, '_PI', file_ext]));
            delete(fig.figure);
            fprintf(disp_format, 'PI');
            
            %% time resolved flow
            fig = copyUIAxes(app.axes8);
            
            % reshape figure window
            fig.figure.Position(3) = app.axes8.Position(3) * 1.15; % make fig 15% wider than axis
            fig.figure.Position(4) = app.axes8.Position(4) * 1.50; % make fig 50% taller than axis
            
            % reshape figure axes
            fig.axes.Units = 'Pixels';
            fig.axes.Position(3:4) = app.axes8.Position(3:4);
            fig.figure.Children(1).Position(1) = fig.figure.Position(3) * 0.875; % move legend to side
            saveas(fig.axes, fullfile(images_dir, [vessel, '_pulsatile_flow', file_ext]));
            delete(fig.figure);
            fprintf(disp_format, 'Time-Resolved');
            
        end
        
        function toolbar_icons(app)
%             app.tb1 = axtoolbar(app.axes1, {'rotate', 'pan', 'zoomin', 'zoomout', 'export'});
%             app.tb2 = axtoolbar(app.axes2, {'rotate', 'pan', 'zoomin', 'zoomout', 'export'});
%             app.tb3 = axtoolbar(app.axes3, {'rotate', 'pan', 'zoomin', 'zoomout', 'export'});
%             app.tb4 = axtoolbar(app.axes4, {'rotate', 'pan', 'zoomin', 'zoomout', 'export'});
%             app.tb5 = axtoolbar(app.axes5, {'rotate', 'pan', 'zoomin', 'zoomout', 'export'});
%             app.tb6 = axtoolbar(app.axes6, {'rotate', 'pan', 'zoomin', 'zoomout', 'export'});
%             app.tb7 = axtoolbar(app.axes7, {'rotate', 'pan', 'zoomin', 'zoomout', 'export'});
%             app.tb8 = axtoolbar(app.axes8, {'rotate', 'pan', 'zoomin', 'zoomout', 'export'});
%             
%             app.tb1.Visible = 'On';
%             app.tb2.Visible = 'On';
%             app.tb3.Visible = 'On';
%             app.tb4.Visible = 'On';
%             app.tb5.Visible = 'On';
%             app.tb6.Visible = 'On';
%             app.tb7.Visible = 'On';
%             app.tb8.Visible = 'On';
        end
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function parameter_plot_gui_OpeningFcn(app, calling_app, structure)
            %%% testing only!
            % test_values(app);
            %%%
            % grab passed-in arguments
            app.Vessel3D_app = calling_app;
            app.handles = structure;
            app.handles = flow_parameters(app.handles);
            
            % set initial values
            upper_bound = length(app.handles.values.area);
            app.handles.values.upper_bound = upper_bound;
            app.SaveDataEndSpinner.Value = upper_bound;
            app.SaveDataEndSpinner.Limits = [1 upper_bound];
            app.VoxelLowerBoundSpinner.Limits = [1 upper_bound];
            app.VoxelLowerBoundSpinner.Value = app.handles.values.lower_bound;
            app.WindowSizeSpinner.Limits = [1 upper_bound];
            app.WindowSizeSpinner.Value = app.handles.values.window;
            vessel = app.VesselNameDropDown.Value;
            app.SaveButton.Text = ['Save '  vessel];
            app.RecommendedDonotusethesevoxelsLabel.Text = ['(Recommended) Do not use these voxels: [', num2str(app.handles.values.removed_voxels), ']'];
            
            % plot
            time_avg_plotter(app);
            time_resolved_plotter(app);
            shading(app);
            update_variance(app);
            % toolbar_icons(app);
        end

        % Value changed function: WindowSizeSpinner
        function WindowSizeSpinnerValueChanged(app, event)
            lower_bound = app.VoxelLowerBoundSpinner.Value;
            upper_bound = app.handles.values.upper_bound;
            window = app.WindowSizeSpinner.Value;
            
            if lower_bound + window > upper_bound
                lower_bound = upper_bound - window;
                app.VoxelLowerBoundSpinner.Value = lower_bound;
                app.VoxelLowerBoundSpinner.Limits = [1 lower_bound];
            end
            
            if window >= 1 && window <= upper_bound
                if lower_bound + window <= upper_bound
                    % to deal with changing window and preventing plotting error
                    % plot new data
                    time_resolved_plotter(app);
                    delete_shading(app);
                    shading(app);
                    update_variance(app);
                end
            end
        end

        % Value changed function: VoxelLowerBoundSpinner
        function VoxelLowerBoundSpinnerValueChanged(app, event)
            lower_bound = app.VoxelLowerBoundSpinner.Value;
            upper_bound = length(app.handles.values.area);
            window = app.WindowSizeSpinner.Value;

            if window >= 1 && window <= upper_bound
                % to deal with changing window and preventing plotting error
                % plot new data
                if lower_bound + window > upper_bound
                    lower_bound = upper_bound - window;
                    app.VoxelLowerBoundSpinner.Value = lower_bound;
                    app.VoxelLowerBoundSpinner.Limits = [1 lower_bound];
                end
                time_resolved_plotter(app);
                delete_shading(app);
                shading(app);
                update_variance(app);
            end
        end

        % Value changed function: VesselNameDropDown
        function VesselNameDropDownValueChanged(app, event)
            value = app.VesselNameDropDown.Value;
            app.SaveButton.Text = ['Save ', num2str(value)];
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.handles.save_start = app.SaveDataStartSpinner.Value;
            app.handles.save_end = app.SaveDataEndSpinner.Value;
            app.handles.window = app.WindowSizeSpinner.Value;
            app.handles.lower_bound = app.VoxelLowerBoundSpinner.Value;
            app.handles.vessel_name = app.VesselNameDropDown.Value;
            fprintf('Saving %s data...\n', app.handles.vessel_name);
            saving_data(app.handles);
            save_plots(app);
            fprintf('Done saving %s data\n', app.handles.vessel_name)
        end

        % Value changed function: LegendSwitch
        function LegendSwitchValueChanged(app, event)
            value = app.LegendSwitch.Value;
            switch value
                case 'Off'
                    % delete legend if it exists
                    try
                        delete(app.lgd);
                    catch
                    end
                otherwise
                    % add legend if window is small
                    % a large legend increases rendering time & decreases
                    % performance
                    update_legend(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.parameter_plot_fig = uifigure('Visible', 'off');
            app.parameter_plot_fig.Name = 'parameter_plot_gui';
            app.axes1 = uiaxes(app.parameter_plot_fig);
            title(app.axes1, 'Area')
            xlabel(app.axes1, 'Centerline Point')
            ylabel(app.axes1, 'cm^2')
            app.axes1.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.WindowSizeSpinnerLabel = uilabel(app.parameter_plot_fig);
            app.WindowSizeSpinnerLabel.Text = 'Window Size';
            app.WindowSizeSpinner = uispinner(app.parameter_plot_fig);
            app.WindowSizeSpinner.Limits = [1 Inf];
            app.WindowSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @WindowSizeSpinnerValueChanged, true);
            app.WindowSizeSpinner.Value = 5;
            app.VoxelLowerBoundSpinnerLabel = uilabel(app.parameter_plot_fig);
            app.VoxelLowerBoundSpinnerLabel.Text = 'Voxel Lower Bound';
            app.VoxelLowerBoundSpinner = uispinner(app.parameter_plot_fig);
            app.VoxelLowerBoundSpinner.Limits = [1 Inf];
            app.VoxelLowerBoundSpinner.ValueChangedFcn = createCallbackFcn(app, @VoxelLowerBoundSpinnerValueChanged, true);
            app.VoxelLowerBoundSpinner.Value = 1;
            app.RecommendedDonotusethesevoxelsLabel = uilabel(app.parameter_plot_fig);
            app.RecommendedDonotusethesevoxelsLabel.Text = '(Recommended) Do not use these voxels: [ ]';
            app.LegendSwitchLabel = uilabel(app.parameter_plot_fig);
            app.LegendSwitchLabel.Text = 'Legend';
            app.LegendSwitch = uiswitch(app.parameter_plot_fig, 'slider');
            app.LegendSwitch.ValueChangedFcn = createCallbackFcn(app, @LegendSwitchValueChanged, true);
            app.LegendSwitch.Value = 'On';
            app.VesselNamesaveasLabel = uilabel(app.parameter_plot_fig);
            app.VesselNamesaveasLabel.Text = 'Vessel Name (save as):';
            app.VesselNameDropDown = uidropdown(app.parameter_plot_fig);
            app.VesselNameDropDown.Items = {'Right VA', 'Left VA', 'Basilar', 'Right ICA', 'Left ICA', 'Right MCA', 'Left MCA', 'Right ACA', 'Left ACA', 'Right PCA', 'Left PCA', 'SS Sinus', 'Straight Sinus', 'Transverse Sinus', 'NdomTsinus', 'Right ICA Petrous', 'Left ICA Petrous', 'Right ICA Cervical', 'Left ICA Cervical'};
            app.VesselNameDropDown.ValueChangedFcn = createCallbackFcn(app, @VesselNameDropDownValueChanged, true);
            app.VesselNameDropDown.Value = 'Right VA';
            app.SaveDataStartSpinnerLabel = uilabel(app.parameter_plot_fig);
            app.SaveDataStartSpinnerLabel.Text = 'Save Data: Start';
            app.SaveDataStartSpinner = uispinner(app.parameter_plot_fig);
            app.SaveDataStartSpinner.Limits = [1 Inf];
            app.SaveDataStartSpinner.Value = 1;
            app.SaveDataEndLabel = uilabel(app.parameter_plot_fig);
            app.SaveDataEndLabel.Text = 'Save Data: End ';
            app.SaveDataEndSpinner = uispinner(app.parameter_plot_fig);
            app.SaveDataEndSpinner.Limits = [1 Inf];
            app.SaveDataEndSpinner.Value = 1;
            app.axes2 = uiaxes(app.parameter_plot_fig);
            title(app.axes2, 'Diameter')
            xlabel(app.axes2, 'Centerline Point')
            ylabel(app.axes2, 'cm')
            app.axes2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.axes3 = uiaxes(app.parameter_plot_fig);
            title(app.axes3, 'Mean Velocity')
            xlabel(app.axes3, 'Centerline Point')
            ylabel(app.axes3, 'cm/s')
            app.axes3.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.axes4 = uiaxes(app.parameter_plot_fig);
            title(app.axes4, 'Max Velocity')
            xlabel(app.axes4, 'Centerline Point')
            ylabel(app.axes4, 'cm/s')
            app.axes4.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.axes5 = uiaxes(app.parameter_plot_fig);
            title(app.axes5, 'Total Flow')
            xlabel(app.axes5, 'Centerline Point')
            ylabel(app.axes5, 'mL')
            app.axes5.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.axes6 = uiaxes(app.parameter_plot_fig);
            title(app.axes6, 'Wall Shear Stress')
            xlabel(app.axes6, 'Centerline Point')
            ylabel(app.axes6, 'Pa')
            app.axes6.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.axes7 = uiaxes(app.parameter_plot_fig);
            title(app.axes7, 'Pulsatility Index')
            xlabel(app.axes7, 'Centerline Point')
            ylabel(app.axes7, 'a.u.')
            app.axes7.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.axes8 = uiaxes(app.parameter_plot_fig);
            title(app.axes8, 'Pulsatile Flow')
            xlabel(app.axes8, 'Centerline Point')
            ylabel(app.axes8, 'mL/s')
            app.axes8.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.VarianceLabel1 = uilabel(app.parameter_plot_fig);
            app.VarianceLabel1.Text = 'Variance: ';
            app.VarianceLabel2 = uilabel(app.parameter_plot_fig);
            app.VarianceLabel2.Text = 'Variance: ';
            app.VarianceLabel3 = uilabel(app.parameter_plot_fig);
            app.VarianceLabel3.Text = 'Variance: ';
            app.VarianceLabel4 = uilabel(app.parameter_plot_fig);
            app.VarianceLabel4.Text = 'Variance: ';
            app.VarianceLabel5 = uilabel(app.parameter_plot_fig);
            app.VarianceLabel5.Text = 'Variance: ';
            app.VarianceLabel6 = uilabel(app.parameter_plot_fig);
            app.VarianceLabel6.Text = 'Variance: ';
            app.VarianceLabel7 = uilabel(app.parameter_plot_fig);
            app.VarianceLabel7.Text = 'Variance: ';
            app.VarianceLabel8 = uilabel(app.parameter_plot_fig);
            app.VarianceLabel8.Text = 'Variance: ';
            app.SaveButton = uibutton(app.parameter_plot_fig, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.parameter_plot_fig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = parameter_plot_gui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.parameter_plot_fig)

            % Execute the startup function
            runStartupFcn(app, @(app)parameter_plot_gui_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.parameter_plot_fig)
        end
    end
end